classdef Animacion_brazo_robotico_3D_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TransformacionesacumuladasTextArea  matlab.ui.control.TextArea
        TransformacionesacumuladasLabel  matlab.ui.control.Label
        IndicalosngulosderotacinLabel  matlab.ui.control.Label
        IndicalaslongitudesdeloseslabonesLabel  matlab.ui.control.Label
        AnimacindeunbrazorobticoLabel  matlab.ui.control.Label
        Rotacin4EditField              matlab.ui.control.NumericEditField
        Rotacin4EditFieldLabel         matlab.ui.control.Label
        Rotacin3EditField              matlab.ui.control.NumericEditField
        Rotacin3EditFieldLabel         matlab.ui.control.Label
        Rotacin2EditField              matlab.ui.control.NumericEditField
        Rotacin2EditFieldLabel         matlab.ui.control.Label
        Rotacin1EditField              matlab.ui.control.NumericEditField
        Rotacin1EditFieldLabel         matlab.ui.control.Label
        TercereslabnmEditField         matlab.ui.control.NumericEditField
        TercereslabnmEditFieldLabel    matlab.ui.control.Label
        SegundoeslabnmEditField        matlab.ui.control.NumericEditField
        SegundoeslabnmEditFieldLabel   matlab.ui.control.Label
        PrimereslabnmEditField         matlab.ui.control.NumericEditField
        PrimereslabnmEditFieldLabel    matlab.ui.control.Label
        TransformacinhomogneaCheckBox  matlab.ui.control.CheckBox
        GeomtricoCheckBox              matlab.ui.control.CheckBox
        AnimarButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    methods (Access = private) 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DibujaEjes_GUI(app,5);
            app.TransformacionesacumuladasTextArea.Visible = false;
            app.TransformacionesacumuladasLabel.Visible = false;
        end

        % Button pushed function: AnimarButton
        function AnimarButtonPushed(app, event)
    cla(app.UIAxes)  
    % Se guardan los valores definidos
    l1 = app.PrimereslabnmEditField.Value;
    l2 = app.SegundoeslabnmEditField.Value;
    l3 = app.TercereslabnmEditField.Value;

    num_pasos = 50;
    theta1 = linspace(0, app.Rotacin1EditField.Value * (pi/180), num_pasos);
    theta2 = linspace(0, app.Rotacin2EditField.Value * (pi/180), num_pasos);
    theta3 = linspace(0, app.Rotacin3EditField.Value * (pi/180), num_pasos);
    theta4 = linspace(0, app.Rotacin4EditField.Value * (pi/180), num_pasos);
    % Se crean valores de 0 al valor de theta para la animación

    DibujaEjes_GUI(app,1.2*(l1 + l2 + l3));
     
     if app.GeomtricoCheckBox.Value
        % --- Método geométrico ---

        app.TransformacionesacumuladasTextArea.Visible = false;
        app.TransformacionesacumuladasLabel.Visible = false;
        
        for i = 1:num_pasos 
            cla(app.UIAxes) 
            DibujaEjes_GUI(app,1.2*(l1 + l2 + l3));

            % Punto 0
            P0 = [0 0 0 1]';
            % Articulación 1 en el plano
            l1x = l1*cos(theta2(i));
            l1y = 0;
            l1z = l1*sin(theta2(i));  
            % Articulación 2 en el plano
            l2x = l2*cos(theta2(i) + theta3(i));
            l2y = 0;
            l2z = l2*sin(theta2(i) + theta3(i));  
            % Efector final en el plano
            l3x = l3*cos(theta2(i) + theta3(i) + theta4(i));
            l3y = 0;
            l3z = l3*sin(theta2(i) + theta3(i) + theta4(i));  

            %Giro de la base
            P1 = [l1x*cos(theta1(i)) l1x*sin(theta1(i)) l1z];
            P2 = [(l1x + l2x)*cos(theta1(i)) (l1x + l2x)*sin(theta1(i)) (l1z + l2z)];
            EF = [(l1x + l2x + l3x)*cos(theta1(i)) (l1x + l2x + l3x)*sin(theta1(i)) (l1z + l2z + l3z)];
        
            DibujaBrazo_GUI(app,P0,P1,P2,EF); % Dibuja todas las articulaciones y eslabones
            pause(0.1)
        end
    elseif app.TransformacinhomogneaCheckBox.Value
        % --- Método de matrices de transformación homogénea ---

        app.TransformacionesacumuladasTextArea.Visible = true;
        app.TransformacionesacumuladasLabel.Visible = true;
        
        % Se crea una área de texto que muestra las transformaciones
        % homogeneas acumuladas
        app.TransformacionesacumuladasTextArea.Value = sprintf(['R(%.2f) R(%.2f) R(%.2f) R(%.2f) T(%.2f)' ...
            ' R(%.2f) T(%.2f) R(%.2f) T(%.2f)'], ...
            app.Rotacin1EditField.Value, 90, 90, app.Rotacin2EditField.Value, l1, app.Rotacin3EditField.Value, l2, app.Rotacin4EditField.Value, l3);

        for i = 1:num_pasos
            cla(app.UIAxes) 
            DibujaEjes_GUI(app,1.2*(l1 + l2 + l3));

            offset1 = -pi/2; % Para compensar la rotacion de 90º en el eje Z
            
            % Se calculan las matrices de transformación homogenea deseada
            R1 = RotZ(theta1(i) + offset1);
            R2 = RotY(pi/2);
            R3 = RotZ(pi/2);
            R4 = RotZ(theta2(i));
            T1 = Tx(l1);
            R5 = RotZ(theta3(i));
            T2 = Tx(l2);
            R6 = RotZ(theta4(i));
            T3 = Tx(l3);

            % Punto 0
            P0 = [0 0 0 1]';    
        
            % Articulación 1
            TH1 = R1 * R2 * R3 * R4 * T1;
            P1 = TH1 * P0;

            % Articulación 2
            TH2 = R1 * R2 * R3 * R4 * T1 * R5 * T2;
            P2 = TH2 * P0;

            % Efector final
            TH3 = R1 * R2 * R3 * R4 * T1 * R5 * T2 * R6 * T3;
            EF = TH3 * P0;

            DibujaBrazo_GUI(app,P0,P1,P2,EF); % Dibuja todas las articulaciones y eslabones

            pause(0.1)
        end
    else
        % Ningún checkbox marcado
        uialert(app.UIFigure, 'Selecciona un método: Geométrico o Transformación homogénea', 'Aviso');
        return
    end
        end

        % Value changed function: TransformacinhomogneaCheckBox
        function TransformacinhomogneaCheckBoxValueChanged(app, event)
                if app.TransformacinhomogneaCheckBox.Value
                app.GeomtricoCheckBox.Value = false;
                end
        end

        % Value changed function: GeomtricoCheckBox
        function GeomtricoCheckBoxValueChanged(app, event)
            if app.GeomtricoCheckBox.Value
                app.TransformacinhomogneaCheckBox.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Animación brazo robótico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AnimarButton = uibutton(app.UIFigure, 'push');
            app.AnimarButton.ButtonPushedFcn = createCallbackFcn(app, @AnimarButtonPushed, true);
            app.AnimarButton.Text = 'Animar';
            app.GeomtricoCheckBox = uicheckbox(app.UIFigure);
            app.GeomtricoCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeomtricoCheckBoxValueChanged, true);
            app.GeomtricoCheckBox.Text = 'Geométrico';
            app.TransformacinhomogneaCheckBox = uicheckbox(app.UIFigure);
            app.TransformacinhomogneaCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransformacinhomogneaCheckBoxValueChanged, true);
            app.TransformacinhomogneaCheckBox.Text = 'Transformación homogénea';
            app.PrimereslabnmEditFieldLabel = uilabel(app.UIFigure);
            app.PrimereslabnmEditFieldLabel.Text = 'Primer eslabón (m)';
            app.PrimereslabnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SegundoeslabnmEditFieldLabel = uilabel(app.UIFigure);
            app.SegundoeslabnmEditFieldLabel.Text = 'Segundo eslabón (m)';
            app.SegundoeslabnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TercereslabnmEditFieldLabel = uilabel(app.UIFigure);
            app.TercereslabnmEditFieldLabel.Text = 'Tercer eslabón (m)';
            app.TercereslabnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Rotacin1EditFieldLabel = uilabel(app.UIFigure);
            app.Rotacin1EditFieldLabel.Text = 'Rotación θ1 (º)';
            app.Rotacin1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Rotacin2EditFieldLabel = uilabel(app.UIFigure);
            app.Rotacin2EditFieldLabel.Text = 'Rotación θ2 (º)';
            app.Rotacin2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Rotacin3EditFieldLabel = uilabel(app.UIFigure);
            app.Rotacin3EditFieldLabel.Text = 'Rotación θ3 (º)';
            app.Rotacin3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Rotacin4EditFieldLabel = uilabel(app.UIFigure);
            app.Rotacin4EditFieldLabel.Text = 'Rotación θ4 (º)';
            app.Rotacin4EditField = uieditfield(app.UIFigure, 'numeric');
            app.AnimacindeunbrazorobticoLabel = uilabel(app.UIFigure);
            app.AnimacindeunbrazorobticoLabel.Text = 'Animación de un brazo robótico';
            app.IndicalaslongitudesdeloseslabonesLabel = uilabel(app.UIFigure);
            app.IndicalaslongitudesdeloseslabonesLabel.Text = 'Indica las longitudes de los eslabones';
            app.IndicalosngulosderotacinLabel = uilabel(app.UIFigure);
            app.IndicalosngulosderotacinLabel.Text = 'Indica los ángulos de rotación';
            app.TransformacionesacumuladasLabel = uilabel(app.UIFigure);
            app.TransformacionesacumuladasLabel.Text = {'Transformaciones '; 'acumuladas'};
            app.TransformacionesacumuladasTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Animacion_brazo_robotico_3D_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end